classdef JMPTaskCreation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BodiesListBox              matlab.ui.control.ListBox
        BodiesListBoxLabel         matlab.ui.control.Label
        JointsListBox              matlab.ui.control.ListBox
        JointsListBoxLabel         matlab.ui.control.Label
        MarkersTextAreaLabel       matlab.ui.control.Label
        MarkersTextArea            matlab.ui.control.TextArea
        EndTimeField               matlab.ui.control.NumericEditField
        ToLabel                    matlab.ui.control.Label
        StartTimeField             matlab.ui.control.NumericEditField
        TimeRangesEditFieldLabel   matlab.ui.control.Label
        MarkersFileEditField       matlab.ui.control.EditField
        MarkersFileEditFieldLabel  matlab.ui.control.Label
        TaskNameEditField          matlab.ui.control.EditField
        TaskNameEditFieldLabel     matlab.ui.control.Label
        AddBodyButton_2            matlab.ui.control.Button
        EditBodyButton             matlab.ui.control.Button
        DeleteJointButton          matlab.ui.control.Button
        EditJointButton            matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        AddBodyButton              matlab.ui.control.Button
        AddJointButton             matlab.ui.control.Button
        EditMarkersButton          matlab.ui.control.Button
        OKButton                   matlab.ui.control.Button
        MarkerFileSearchButton     matlab.ui.control.Button
        EnabledCheckBox            matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        JMPBase;
        TaskApp JMPTaskCreation;
        JMPTask = struct();
        JMPJoint cell;
        JMPJointSet struct = struct("JMPJointSet", "")
        JMPBody cell;
        JMPBodySet struct = struct("JMPBodySet", "")
        JointNames string;
        BodyNames string;
        editingFlag logical = false;
    end
    
    methods (Access = public)


        function setSelectedObjects(app, objects)
            % Function to assign objects selected by ObjectSelectionWindow
            app.JMPTask.marker_names = objects;
            app.MarkersTextArea.Value = strjoin(objects, " ");
        end

        function objects = getSelectedObjects(app)
            objects = app.JMPTask.marker_names;
        end

        function jointSet = getJMPJointSet(app)
            jointSet = app.JMPJoint;
        end
        
        function addJoint(app, jointSet)
            app.JMPJoint{end+1} = jointSet;
            app.JointNames(end+1) = jointSet.Attributes.name;
            app.JointsListBox.Items = app.JointNames;
        end

        function bodySet = getJMPBodySet(app)
            bodySet = app.JMPBody;
        end
        
        function addBody(app, bodySet)
            app.JMPBody{end+1} = bodySet;
            app.BodyNames(end+1) = bodySet.Attributes.name;
            app.BodyListBox.Items = app.BodyNames;
        end

        function joints = getJMPModelJoints(app)
            joints = app.JMPBase.getJMPModelJoints();
        end

        function bodies = getJMPModelBodies(app)
            bodies = app.JMPBase.getJMPModelBodies();
        end
    end
    
    methods (Access = private)
        
        function makeEmptyTask(app)
            app.JMPTask.Attributes.name = "Default";
            app.JMPTask.index = 0;
            app.JMPTask.is_enabled = 'true';
            app.JMPTask.time_range = [0 0];
            app.JMPTask.marker_file_name = "";
            app.JMPTask.marker_names = [""];
            app.JMPTask.JMPJointSet.JMPJoint = {};
        end

        function loadTask(app, task)
            app.JMPTask = task;
            app.TaskNameEditField.Value = task.Attributes.name;
            if strcmp(task.is_enabled, 'true')
                app.EnabledCheckBox.Value = true;
            else
                app.EnabledCheckBox.Value = false;
            end
            app.StartTimeField.Value = task.time_range(1);
            app.EndTimeField.Value = task.time_range(2);
            app.MarkersFileEditField.Value = task.marker_file_name;
            app.MarkersTextArea.Value = strjoin(task.marker_names, " ");
            for i = 1 : numel(task.JMPJointSet.JMPJoint)
                app.JointNames(i) = task.JMPJointSet.JMPJoint{i}.Attributes.name;
            end
            app.JointsListBox.Items = app.JointNames;

        end

        function parseMarkerFile(app)
            import org.opensim.modeling.TimeSeriesTableVec3
            if ~exist(app.JMPTask.marker_file_name)
                return
            end
            timeSeriesTable = TimeSeriesTableVec3(app.JMPTask.marker_file_name);
            numTimePoints = timeSeriesTable.getNumRows;
            timeVector = timeSeriesTable.getIndependentColumn;
    

            startTime = double(timeVector.get(0));
            endTime = double(timeVector.get(numTimePoints-1));
            app.JMPTask.time_range = ...
                [startTime endTime];

            app.StartTimeField.Value = double(app.JMPTask.time_range(1));
            app.EndTimeField.Value = double(app.JMPTask.time_range(2));
        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, JMPBase, JMPTask)
            app.JMPBase = JMPBase;
            if isempty(JMPTask)
                app.makeEmptyTask()
            else
                app.loadTask(JMPTask)
                app.editingFlag = true;
            end
        end

        % Button pushed function: MarkerFileSearchButton
        function MarkerFileSearchButtonPushed(app, event)
        [file, path] = uigetfile('*.trc', "Select a trc marker file.");
        % User hit "Cancel"
        if isequal(file, 0)
            return
        end
  
        fullpath = fullfile(path, file);
    
        % Update the text field
        app.MarkersFileEditField.Value = fullpath;
        app.JMPTask.marker_file_name = fullpath;

        parseMarkerFile(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if ~app.editingFlag
                app.JMPTask.index = numel(app.JMPBase.getJMPTaskList)+1;
                app.JMPTask.JMPJointSet.JMPJoint = app.JMPJoint;
                app.JMPBase.addJMPTask(app.JMPTask);
            else
                app.JMPBase.editJMPTask(app.JMPTask, app.JMPTask.index)
            end

            delete(app.UIFigure);
        end

        % Button pushed function: EditMarkersButton
        function EditMarkersButtonPushed(app, event)
            modelMarkers = app.JMPBase.getJMPModelMarkers();
            ObjectSelectionWindow(app, modelMarkers, ...
                app.JMPTask.marker_names);
        end

        % Button pushed function: AddJointButton
        function AddJointButtonPushed(app, event)
            JMPJointSelection(app, app.JMPBody);
        end

        % Button pushed function: AddBodyButton
        function AddBodyButtonPushed(app, event)

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Value changed function: EnabledCheckBox
        function EnabledCheckBoxValueChanged(app, event)
            if app.EnabledCheckBox.Value
                app.JMPTask.is_enabled = 'true';
            else
                app.JMPTask.is_enabled = 'false';
            end
        end

        % Value changed function: TaskNameEditField
        function TaskNameEditFieldValueChanged(app, event)
            taskName = app.TaskNameEditField.Value;
            app.JMPTask.Attributes.name = convertStringsToChars(taskName);
        end

        % Value changed function: MarkersFileEditField
        function MarkersFileEditFieldValueChanged(app, event)
            parseMarkerFile(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnabledCheckBox = uicheckbox(app.UIFigure);
            app.EnabledCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnabledCheckBoxValueChanged, true);
            app.EnabledCheckBox.Text = 'Enabled';
            app.EnabledCheckBox.Value = true;
            app.MarkerFileSearchButton = uibutton(app.UIFigure, 'push');
            app.MarkerFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerFileSearchButtonPushed, true);
            app.MarkerFileSearchButton.Icon = fullfile(pathToMLAPP, 'Images', 'folderIcon.svg');
            app.MarkerFileSearchButton.Text = '';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.EditMarkersButton = uibutton(app.UIFigure, 'push');
            app.EditMarkersButton.ButtonPushedFcn = createCallbackFcn(app, @EditMarkersButtonPushed, true);
            app.EditMarkersButton.Text = 'Edit Markers';
            app.AddJointButton = uibutton(app.UIFigure, 'push');
            app.AddJointButton.ButtonPushedFcn = createCallbackFcn(app, @AddJointButtonPushed, true);
            app.AddJointButton.Text = 'Add Joint';
            app.AddBodyButton = uibutton(app.UIFigure, 'push');
            app.AddBodyButton.ButtonPushedFcn = createCallbackFcn(app, @AddBodyButtonPushed, true);
            app.AddBodyButton.Text = 'Add Body';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.EditJointButton = uibutton(app.UIFigure, 'push');
            app.EditJointButton.Text = 'Edit Joint';
            app.DeleteJointButton = uibutton(app.UIFigure, 'push');
            app.DeleteJointButton.Text = 'Delete Joint';
            app.EditBodyButton = uibutton(app.UIFigure, 'push');
            app.EditBodyButton.Text = 'Edit Body';
            app.AddBodyButton_2 = uibutton(app.UIFigure, 'push');
            app.AddBodyButton_2.Text = 'Add Body';
            app.TaskNameEditFieldLabel = uilabel(app.UIFigure);
            app.TaskNameEditFieldLabel.Text = 'Task Name';
            app.TaskNameEditField = uieditfield(app.UIFigure, 'text');
            app.TaskNameEditField.ValueChangedFcn = createCallbackFcn(app, @TaskNameEditFieldValueChanged, true);
            app.TaskNameEditField.Value = 'Default';
            app.MarkersFileEditFieldLabel = uilabel(app.UIFigure);
            app.MarkersFileEditFieldLabel.Text = 'Markers File';
            app.MarkersFileEditField = uieditfield(app.UIFigure, 'text');
            app.MarkersFileEditField.ValueChangedFcn = createCallbackFcn(app, @MarkersFileEditFieldValueChanged, true);
            app.TimeRangesEditFieldLabel = uilabel(app.UIFigure);
            app.TimeRangesEditFieldLabel.Text = 'Time Range (s):';
            app.StartTimeField = uieditfield(app.UIFigure, 'numeric');
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To:';
            app.EndTimeField = uieditfield(app.UIFigure, 'numeric');
            app.MarkersTextArea = uitextarea(app.UIFigure);
            app.MarkersTextArea.Editable = 'off';
            app.MarkersTextAreaLabel = uilabel(app.UIFigure);
            app.MarkersTextAreaLabel.Text = 'Markers';
            app.JointsListBoxLabel = uilabel(app.UIFigure);
            app.JointsListBoxLabel.Text = 'Joints';
            app.JointsListBox = uilistbox(app.UIFigure);
            app.JointsListBox.Items = {''};
            app.JointsListBox.Value = {};
            app.BodiesListBoxLabel = uilabel(app.UIFigure);
            app.BodiesListBoxLabel.Text = 'Bodies';
            app.BodiesListBox = uilistbox(app.UIFigure);
            app.BodiesListBox.Items = {''};
            app.BodiesListBox.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JMPTaskCreation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end