classdef MTPBase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunButton                       matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        LoadSettingsFileButton          matlab.ui.control.Button
        RcnlLogo                        matlab.ui.control.Image
        MTPInputsButton                 matlab.ui.control.Button
        SynergyExtrapolationButton      matlab.ui.control.Button
        MuscletendonLengthInitalizationButton  matlab.ui.control.Button
        MuscleGroupsButton              matlab.ui.control.Button
        MTPImage                        matlab.ui.control.Image
        Mask1                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        SynergyExtrapolationTab         matlab.ui.container.Tab
        NumSynergiesSpinner             matlab.ui.control.Spinner
        NumSynergiesSpinnerLabel        matlab.ui.control.Label
        EnableSynergyExtrapolationCheckBox  matlab.ui.control.CheckBox
        MuscleTendonLengthInitializationTab  matlab.ui.container.Tab
        EnableMuscletendonLengthInitializationCheckBox  matlab.ui.control.CheckBox
        InputModelFileError_2           matlab.ui.control.Image
        PassiveDataInputDirectoryEditField  matlab.ui.control.EditField
        PassiveDataInputDirectoryEditFieldLabel  matlab.ui.control.Label
        InputModelFileSearchButton_2    matlab.ui.control.Button
        MuscleGroupsTab                 matlab.ui.container.Tab
        EditJMPTaskButton_4             matlab.ui.control.Button
        EditJMPTaskButton_3             matlab.ui.control.Button
        EditJMPTaskButton_2             matlab.ui.control.Button
        EditJMPTaskButton               matlab.ui.control.Button
        ActivationMuscleGroupsTextArea  matlab.ui.control.TextArea
        ActivationMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        CollectedEMGMuscleGroupsTextArea  matlab.ui.control.TextArea
        CollectedEMGMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        MissingEMGMuscleGroupsTextArea  matlab.ui.control.TextArea
        MissingEMGMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        NormalizedFiberLengthMuscleGroupsTextArea  matlab.ui.control.TextArea
        NormalizedFiberLengthMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        InputsTab                       matlab.ui.container.Tab
        EditJMPTaskButton_5             matlab.ui.control.Button
        CoordinateListError             matlab.ui.control.Image
        CoordinatesListTextAreaLabel    matlab.ui.control.Label
        CoordinatesListTextArea         matlab.ui.control.TextArea
        InputDataDirectoryEditFieldLabel  matlab.ui.control.Label
        ResultsDirectoryError           matlab.ui.control.Image
        ResultsDirectoryEditField       matlab.ui.control.EditField
        ResultsDirectoryEditFieldLabel  matlab.ui.control.Label
        ResultsDirectorySearchButton    matlab.ui.control.Button
        InputDataError                  matlab.ui.control.Image
        InputDataEditField              matlab.ui.control.EditField
        InputDataSearchButton           matlab.ui.control.Button
        InputOsimxFileError             matlab.ui.control.Image
        InputOsimxFileEditField         matlab.ui.control.EditField
        InputOsimxFileEditFieldLabel    matlab.ui.control.Label
        InputOsimxFileSearchButton      matlab.ui.control.Button
        InputModelFileError             matlab.ui.control.Image
        InputModelFileEditField         matlab.ui.control.EditField
        InputModelFileEditFieldLabel    matlab.ui.control.Label
        InputModelFileSearchButton      matlab.ui.control.Button
        MuscletendonModelPersonalizationToolLabel  matlab.ui.control.Label
    end

    
    properties (Access = private, SetObservable)
        input_model_file string = "";
        input_osimx_file string = "";
        data_directory string = "";
        results_directory string = "";
        coordinate_list string = [];
        activation_muscle_groups string = [];
        normalized_fiber_length_muscle_groups string = [];
        missing_emg_channel_muscle_groups string = [];
        collected_emg_channel_muscle_groups string = [];
        model_markers string;
        model_bodies string;
        model_joints string;
        MuscleTendonLengthInitialization struct = struct([])
        MTPSynergyExtrapolation struct = struct([])
        MTPTaskList struct = struct([])

        ErrorFlag logical = false;
        currentSettingsFile string; % Variable to store the current settings file name.
        needToSave logical = false; % flag indicating if the settings file changed and needs to be saved before running.
        modelErrorFlag logical = false;
    end % Description
    
    properties (Access = public)
        accuracy double = 1e-6;
        diff_min_change double = 0.001;
        optimality_tolerance double = 1e-5;
        function_tolerance double = 1e-5;
        step_tolerance double = 1e-5;
        max_function_evaluations double = 200;
    end
    
    methods (Access = private)
        
        function ErrorsCallback(app)

        end

        function EnableActionsCallback(app)

        end

        function settingsTree = setOptimizationParams(app, settingsTree)
            settingsTree.accuracy = app.accuracy;
            settingsTree.diff_min_change = app.diff_min_change;
            settingsTree.optimality_tolerance = app.optimality_tolerance;
            settingsTree.function_tolerance = app.function_tolerance;
            settingsTree.step_tolerance = app.step_tolerance;
            settingsTree.max_function_evaluations = app.max_function_evaluations;
        end
    end
    
    methods (Access = public)
        
        function modelFile = getInputModelFile(app)
            modelFile = app.input_model_file;
        end

        function model_markers = getModelMarkers(app)
            model_markers = app.model_markers;
        end

        function model_bodies = getModelBodies(app)
            model_bodies = app.model_bodies;
        end

        function model_joints = getModelJoints(app)
            model_joints = app.model_joints;
        end

        function SetModelMarkers(app, markers)
            app.model_markers = markers;
        end

        function SetModelJoints(app, joints)
            app.model_joints = joints;
        end

        function SetModelBodies(app, bodies)
            app.model_bodies = bodies;
        end
    end

    properties(Access = private)  % listner properties
        InputModelFileListener
        OsimXFileListener
        DataDirectoryListener
        ResultsDirectoryListener
        CoordinateListListener
    end

    methods (Access = private) % listener methods
        function makeListeners(app)
            app.InputModelFileListener = addlistener(app, ...
                'input_model_file', 'PostSet', ...
                @(src,event)InputModelFileListenerFunction(app));

            app.OsimXFileListener = addlistener(app, ...
                'input_osimx_file', 'PostSet', ...
                @(src,event)OsimXFileListenerFunction(app));

            app.DataDirectoryListener = addlistener(app, ...
                'data_directory', 'PostSet', ...
                @(src,event)DataDirectoryListenerFunction(app));

            app.ResultsDirectoryListener = addlistener(app, ...
                'results_directory', 'PostSet', ...
                @(src,event)ResultsDirectoryListenerFunction(app));

            app.CoordinateListListener = addlistener(app, ...
                'coordinate_list', 'PostSet', ...
                @(src,event)CoordinateListListenerFunction(app));
        end

        function InputModelFileListenerFunction(app)
            app.InputModelFileEditField.Value = getRelativePath( ...
                app.input_model_file);
            parseModelFileGui(app, app.input_model_file);
            app.ErrorsCallback()
            app.needToSave = true;
        end

        function OsimXFileListenerFunction(app)
            app.InputOsimxFileEditField.Value = getRelativePath( ...
                app.input_osimx_file);
            app.needToSave = true;
        end

        function DataDirectoryListenerFunction(app)
            app.InputDataEditField.Value = getRelativePath( ...
                app.data_directory);
            app.needToSave = true;
        end

        function ResultsDirectoryListenerFunction(app)
            app.ResultsDirectoryEditField.Value = getRelativePath( ...
                app.results_directory);
            app.needToSave = true;
        end

        function CoordinateListListenerFunction(app)
            app.CoordinatesListTextArea.Value = app.coordinate_list;
            app.needToSave = true;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.makeListeners()
        end

        % Button pushed function: MTPInputsButton
        function MTPInputsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InputsTab;
        end

        % Button pushed function: SynergyExtrapolationButton
        function SynergyExtrapolationButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SynergyExtrapolationTab;
        end

        % Button pushed function: MuscletendonLengthInitalizationButton
        function MuscletendonLengthInitalizationButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.MuscleTendonLengthInitializationTab;
        end

        % Button pushed function: MuscleGroupsButton
        function MuscleGroupsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.MuscleGroupsTab;
            
        end

        % Button pushed function: InputModelFileSearchButton
        function InputModelFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osim', "Select an OpenSim Model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.input_model_file = fullfile(path, file);
        end

        % Button pushed function: InputOsimxFileSearchButton
        function InputOsimxFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osimx', "Select an Osimx model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.input_osimx_file = fullfile(path, file);
        end

        % Button pushed function: ResultsDirectorySearchButton
        function ResultsDirectorySearchButtonPushed(app, event)
            folder = uigetdir("Select a folder to store your results");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.results_directory = folder;
        end

        % Button pushed function: InputDataSearchButton
        function InputDataSearchButtonPushed(app, event)
            folder = uigetdir("Select your input data folder");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.data_directory = folder;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MuscletendonModelPersonalizationToolLabel = uilabel(app.UIFigure);
            app.MuscletendonModelPersonalizationToolLabel.Text = 'Muscle-tendon Model Personalization Tool';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputModelFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputModelFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputModelFileSearchButtonPushed, true);
            app.InputModelFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputModelFileSearchButton.Text = '';
            app.InputModelFileEditFieldLabel = uilabel(app.InputsTab);
            app.InputModelFileEditFieldLabel.Text = 'Input Model File';
            app.InputModelFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputModelFileError = uiimage(app.InputsTab);
            app.InputModelFileError.Visible = 'off';
            app.InputModelFileError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputOsimxFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputOsimxFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputOsimxFileSearchButtonPushed, true);
            app.InputOsimxFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputOsimxFileSearchButton.Text = '';
            app.InputOsimxFileEditFieldLabel = uilabel(app.InputsTab);
            app.InputOsimxFileEditFieldLabel.Text = 'Input Osimx File';
            app.InputOsimxFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputOsimxFileError = uiimage(app.InputsTab);
            app.InputOsimxFileError.Visible = 'off';
            app.InputOsimxFileError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputDataSearchButton = uibutton(app.InputsTab, 'push');
            app.InputDataSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputDataSearchButtonPushed, true);
            app.InputDataSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputDataSearchButton.Text = '';
            app.InputDataEditField = uieditfield(app.InputsTab, 'text');
            app.InputDataError = uiimage(app.InputsTab);
            app.InputDataError.Visible = 'off';
            app.InputDataError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.ResultsDirectorySearchButton = uibutton(app.InputsTab, 'push');
            app.ResultsDirectorySearchButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsDirectorySearchButtonPushed, true);
            app.ResultsDirectorySearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.ResultsDirectorySearchButton.Text = '';
            app.ResultsDirectoryEditFieldLabel = uilabel(app.InputsTab);
            app.ResultsDirectoryEditFieldLabel.Text = 'Results Directory';
            app.ResultsDirectoryEditField = uieditfield(app.InputsTab, 'text');
            app.ResultsDirectoryError = uiimage(app.InputsTab);
            app.ResultsDirectoryError.Visible = 'off';
            app.ResultsDirectoryError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputDataDirectoryEditFieldLabel = uilabel(app.InputsTab);
            app.InputDataDirectoryEditFieldLabel.Text = 'Input Data Directory';
            app.CoordinatesListTextArea = uitextarea(app.InputsTab);
            app.CoordinatesListTextArea.Editable = 'off';
            app.CoordinatesListTextAreaLabel = uilabel(app.InputsTab);
            app.CoordinatesListTextAreaLabel.Text = 'Coordinates List';
            app.CoordinateListError = uiimage(app.InputsTab);
            app.CoordinateListError.Visible = 'off';
            app.CoordinateListError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.EditJMPTaskButton_5 = uibutton(app.InputsTab, 'push');
            app.EditJMPTaskButton_5.Text = 'Edit';
            app.MuscleGroupsTab = uitab(app.TabGroup);
            app.NormalizedFiberLengthMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.NormalizedFiberLengthMuscleGroupsTextAreaLabel.Text = 'Normalized Fiber Length Muscle Groups';
            app.NormalizedFiberLengthMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.NormalizedFiberLengthMuscleGroupsTextArea.Editable = 'off';
            app.MissingEMGMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.MissingEMGMuscleGroupsTextAreaLabel.Text = 'Missing EMG Muscle Groups';
            app.MissingEMGMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.MissingEMGMuscleGroupsTextArea.Editable = 'off';
            app.CollectedEMGMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.CollectedEMGMuscleGroupsTextAreaLabel.Text = 'Collected EMG Muscle Groups';
            app.CollectedEMGMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.CollectedEMGMuscleGroupsTextArea.Editable = 'off';
            app.ActivationMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.ActivationMuscleGroupsTextAreaLabel.Text = 'Activation Muscle Groups';
            app.ActivationMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.ActivationMuscleGroupsTextArea.Editable = 'off';
            app.EditJMPTaskButton = uibutton(app.MuscleGroupsTab, 'push');
            app.EditJMPTaskButton.Text = 'Edit';
            app.EditJMPTaskButton_2 = uibutton(app.MuscleGroupsTab, 'push');
            app.EditJMPTaskButton_2.Text = 'Edit';
            app.EditJMPTaskButton_3 = uibutton(app.MuscleGroupsTab, 'push');
            app.EditJMPTaskButton_3.Text = 'Edit';
            app.EditJMPTaskButton_4 = uibutton(app.MuscleGroupsTab, 'push');
            app.EditJMPTaskButton_4.Text = 'Edit';
            app.MuscleTendonLengthInitializationTab = uitab(app.TabGroup);
            app.InputModelFileSearchButton_2 = uibutton(app.MuscleTendonLengthInitializationTab, 'push');
            app.InputModelFileSearchButton_2.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputModelFileSearchButton_2.Text = '';
            app.PassiveDataInputDirectoryEditFieldLabel = uilabel(app.MuscleTendonLengthInitializationTab);
            app.PassiveDataInputDirectoryEditFieldLabel.Text = 'Passive Data Input Directory';
            app.PassiveDataInputDirectoryEditField = uieditfield(app.MuscleTendonLengthInitializationTab, 'text');
            app.InputModelFileError_2 = uiimage(app.MuscleTendonLengthInitializationTab);
            app.InputModelFileError_2.Visible = 'off';
            app.InputModelFileError_2.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.EnableMuscletendonLengthInitializationCheckBox = uicheckbox(app.MuscleTendonLengthInitializationTab);
            app.EnableMuscletendonLengthInitializationCheckBox.Text = 'Enable Muscle-tendon Length Initialization';
            app.SynergyExtrapolationTab = uitab(app.TabGroup);
            app.EnableSynergyExtrapolationCheckBox = uicheckbox(app.SynergyExtrapolationTab);
            app.EnableSynergyExtrapolationCheckBox.Text = 'Enable Synergy Extrapolation';
            app.NumSynergiesSpinnerLabel = uilabel(app.SynergyExtrapolationTab);
            app.NumSynergiesSpinnerLabel.Text = 'Num Synergies';
            app.NumSynergiesSpinner = uispinner(app.SynergyExtrapolationTab);
            app.Mask1 = uiimage(app.UIFigure);
            app.Mask1.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'greyMask.png');
            app.MTPImage = uiimage(app.UIFigure);
            app.MTPImage.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'mtpFigure.png');
            app.MuscleGroupsButton = uibutton(app.UIFigure, 'push');
            app.MuscleGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @MuscleGroupsButtonPushed, true);
            app.MuscleGroupsButton.Enable = 'off';
            app.MuscleGroupsButton.Text = 'Muscle Groups';
            app.MuscletendonLengthInitalizationButton = uibutton(app.UIFigure, 'push');
            app.MuscletendonLengthInitalizationButton.ButtonPushedFcn = createCallbackFcn(app, @MuscletendonLengthInitalizationButtonPushed, true);
            app.MuscletendonLengthInitalizationButton.Enable = 'off';
            app.MuscletendonLengthInitalizationButton.Text = 'Muscle-tendon Length Initalization';
            app.SynergyExtrapolationButton = uibutton(app.UIFigure, 'push');
            app.SynergyExtrapolationButton.ButtonPushedFcn = createCallbackFcn(app, @SynergyExtrapolationButtonPushed, true);
            app.SynergyExtrapolationButton.Enable = 'off';
            app.SynergyExtrapolationButton.Text = 'Synergy Extrapolation';
            app.MTPInputsButton = uibutton(app.UIFigure, 'push');
            app.MTPInputsButton.ButtonPushedFcn = createCallbackFcn(app, @MTPInputsButtonPushed, true);
            app.MTPInputsButton.Text = 'MTP Inputs';
            app.RcnlLogo = uiimage(app.UIFigure);
            app.RcnlLogo.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'rcnlIcon.png');
            app.LoadSettingsFileButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsFileButton.Text = 'Load';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.Text = 'Help';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MTPBase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end